classdef ProyectoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UnidaddeEfectosdeAudioUIFigure  matlab.ui.Figure
        Efectos1Panel                   matlab.ui.container.Panel
        EcoCheckBox                     matlab.ui.control.CheckBox
        TremoloCheckBox                 matlab.ui.control.CheckBox
        FlangerCheckBox                 matlab.ui.control.CheckBox
        DistCheckBox                    matlab.ui.control.CheckBox
        VibratoCheckBox                 matlab.ui.control.CheckBox
        AplicarEfecto1                  matlab.ui.control.Button
        ReproducirEfecto1               matlab.ui.control.Button
        LampEfecto1                     matlab.ui.control.Lamp
        SliderEcoDelay                  matlab.ui.control.Slider
        SecLabel                        matlab.ui.control.Label
        Slider_2                        matlab.ui.control.Slider
        HzLabel_5                       matlab.ui.control.Label
        SliderTremolo                   matlab.ui.control.Slider
        SliderVibratoFreq               matlab.ui.control.Slider
        HzLabel_6                       matlab.ui.control.Label
        HzLabel_7                       matlab.ui.control.Label
        SliderVibratoMS                 matlab.ui.control.Slider
        msLabel                         matlab.ui.control.Label
        SliderDistVal                   matlab.ui.control.Slider
        HzLabel_8                       matlab.ui.control.Label
        SliderEcoAlfa                   matlab.ui.control.Slider
        SecLabel_2                      matlab.ui.control.Label
        ArchivoEntradaPanel             matlab.ui.container.Panel
        ExaminarButton_2                matlab.ui.control.Button
        ReproducirarchivoButton         matlab.ui.control.Button
        ArchivoAudioLabel               matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        DuracionLabel                   matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        DurationLabel                   matlab.ui.control.Label
        CanalesLabel                    matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        NumCanalesLabel                 matlab.ui.control.Label
        Banda1Panel                     matlab.ui.container.Panel
        FreqaltaEditFieldLabel          matlab.ui.control.Label
        FreqaltaEditField               matlab.ui.control.NumericEditField
        FreqbajaEditFieldLabel          matlab.ui.control.Label
        FreqbajaEditField               matlab.ui.control.NumericEditField
        HzLabel                         matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        AplicarBanda1                   matlab.ui.control.Button
        LampBanda1                      matlab.ui.control.Lamp
        EfectosPanel_2                  matlab.ui.container.Panel
        AplicarEfecto2                  matlab.ui.control.Button
        ReproducirEfecto2               matlab.ui.control.Button
        LampEfecto2                     matlab.ui.control.Lamp
        EcoCheckBox_2                   matlab.ui.control.CheckBox
        TremoloCheckBox_2               matlab.ui.control.CheckBox
        FlangerCheckBox_2               matlab.ui.control.CheckBox
        EfectoCheckBox_7                matlab.ui.control.CheckBox
        EfectoCheckBox_8                matlab.ui.control.CheckBox
        Slider_4                        matlab.ui.control.Slider
        Slider_5                        matlab.ui.control.Slider
        Slider_6                        matlab.ui.control.Slider
        GananciaPanel                   matlab.ui.container.Panel
        GananciadefiltrosSlider         matlab.ui.control.Slider
        AplicarGanancia                 matlab.ui.control.Button
        ReproducirGanancia              matlab.ui.control.Button
        LampGanancia                    matlab.ui.control.Lamp
        Banda2Panel                     matlab.ui.container.Panel
        FreqaltaEditField_2Label        matlab.ui.control.Label
        FreqaltaEditField_2             matlab.ui.control.NumericEditField
        FreqbajaEditField_2Label        matlab.ui.control.Label
        FreqbajaEditField_2             matlab.ui.control.NumericEditField
        HzLabel_3                       matlab.ui.control.Label
        HzLabel_4                       matlab.ui.control.Label
        AplicarBanda2                   matlab.ui.control.Button
        LampBanda2                      matlab.ui.control.Lamp
        EspectroArchivoEntradaPanel     matlab.ui.container.Panel
        EspectroEntradaAxes             matlab.ui.control.UIAxes
        EspectroEfectoPanel             matlab.ui.container.Panel
        EspectroEfectoAxes              matlab.ui.control.UIAxes
        EfectoDropDownLabel             matlab.ui.control.Label
        EfectoDropDown                  matlab.ui.control.DropDown
        EspectroArchivoSalidaPanel      matlab.ui.container.Panel
        EspectroSalidaAxes              matlab.ui.control.UIAxes
        ArchivoSalidaPanel              matlab.ui.container.Panel
        ExaminarButton_3                matlab.ui.control.Button
        GuardarButton                   matlab.ui.control.Button
        ArchivoAudioLabel_2             matlab.ui.control.Label
    end


    methods (Access = private)
    
        function plotSpectrum(app, axes, data, Fs)
            
            N = Fs;

            XT = abs(fft(data, N)/N);

            XP = XT(1:N/2+1);
            XP(2:end-1) = 2*XP(2:end-1);

            f = Fs*(0:(N/2))/N;
                
            plot(axes, f(1:20000), XP(1:20000));
            
        end
        
        function y = bandPass(app, x, Fl, Fh, Fs)
            
            N = 201;
            n = -floor(N/2):floor(N/2);
            w = hamming(N);


            fcl = Fh/Fs;
            h_low = 2*fcl*sinc(2*fcl*n)' .* w;

            fch = Fl/Fs;
           h_high = (sinc(n) - 2*fch*sinc(2*fch*n))' .* w;

            
            y_low = filter(h_low, [1], x);
            y_band = filter(h_high, [1], y_low);
            y = y_band;
        end
        
        function y = tremolo(app, x, val, Fs)
            t = ((0:length(x)-1)/Fs)';
            sigAux = cos(2*pi*val*t); %Señal de modulacion
            y = x .* sigAux;
        end
        
        
        function y = flanger(app, x, val, Fs)
            maxDelay = 0.003; % Definiendo Retardo máximo
            amp = 0.7; % Coeficiente recomendado para el efecto
            rate = val; % oscilacion,  Hz
                        
                        % crear señales de control
            index = 1:length(x);
            sigRef = (sin(2*pi*index*(rate/Fs)))';
            maxSampDelay = round(maxDelay*Fs); % obteniendo delay máximo en muestras
            y = zeros(length(x),1); % creando vectores
            y(1:maxSampDelay) = x(1:maxSampDelay); % Para evitar muestrear datos innecesarios.
                        
                        % Procesando Audio
            for i = (maxSampDelay+1):length(x)
                actualSig=abs(sigRef(i));    % dato actual
                actualDelay=ceil(actualSig*maxSampDelay);  % Generando el nuevo delay
                y(i) = (amp*x(i)) + amp*(x(i-actualDelay));   % Añadir dato con delay
            end
                        
                        % Normalizando                    
            if (abs(max(y)) > abs(min(y)))
                y = y./abs(max(y));
            else
                y = y./abs(min(y));
            end
        end
        
        
        function y = vibrato(app, x, Freq, ms, Fs)
            Modfreq = Freq; %en Khz
            Width = ms / 1000; % en Segundos (0.8 milisegundos)
            ya_alt=0;
            Delay=round(Width*Fs); % Delay base, per o en muestras
            Width_P=round(Width*Fs); % modulacion del pulso, en muestras
            
            Modfreq_p=Modfreq/Fs; % Frecuencia de modulacion, en muestras
            Len=length(x);
            L=2+Delay+Width_P*2; % Ancho del delay
            
            % Crear vectores contenedores
            Delayline=zeros(L,1);
            y=zeros(size(x));
            
            % Creando Efecto
            for n=1:(Len-1)
                M = Modfreq_p;
                alter = sin(M*2*pi*n);
                modulador = 1+Delay+Width_P*alter;
                i=floor(modulador);
                frac=modulador-i;
                Delayline=[x(n);Delayline(1:L-1)];
                
                y(n,1)=Delayline(i+1)*frac+Delayline(i)*(1-frac);
            end
        end
        
        function y = ring(app, x, Fc, Fs)
            index = 1:length(x);
            % Frecuencia de oscilacion
            carrier = sin(2*pi*index*(Fc/Fs))';
            y = x.*carrier;
        end
        
        function y = eco(app, x, alfa, delay, Fs)
            
            y = zeros(length(x), 1);
            y_n = 0;

            for n = 1:length(x)
                y(n) = x(n) + alfa*y_n;
   
                if (n > delay*Fs)
                    y_n = y(n-floor(delay*Fs));
                end
            end
        end
        
        function efectos1Enable(app, enable)
            app.EcoCheckBox.Enable = enable;
            %app.TremoloCheckBox = enable; % bugged for some reason
            app.FlangerCheckBox = enable;
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: ExaminarButton_2
        function ExaminarButton_2Pushed(app, event)
            global inputPath;
            global inputFile;
            global fileSelected;
            global songData;
            global Fs;
            global channels;
            
            global ecoFlag;
            global ecoDelay;
            global ecoAlfa;
            global tremoloFlag;
            global tremoloValue;
            global flangerFlag;
            global flangerValue;
            global vibratoFlag;
            global vibratoFreqValue;
            global vibratoMSValue;
            global distFlag;
            global distValue;
            
            fileSelected = 0;
            
            
            [inputFile, inputPath] = uigetfile(".wav");
            if isequal(inputFile,0)
                % not file
                app.ArchivoAudioLabel.Text = "<Archivo Audio>";                
                fileSelected = 0;
            else
                % file selected
                app.ArchivoAudioLabel.Text = inputFile;
                fileSelected = 1;
                
                % load audio data
                [songData, Fs] = audioread(join([inputPath inputFile], ""));
                
                % cantidad de canales
                channels = size(songData,2);
                app.NumCanalesLabel.Text = string(channels);
                
                % mostrar duracion
                duration = size(songData, 1)/Fs;
                min = floor(duration/60);
                secs = duration - min*60;
                app.DurationLabel.Text = sprintf("%02.0f:%05.2f", min, secs);
                
                % graficar espectro entrada
                app.plotSpectrum(app.EspectroEntradaAxes, songData, Fs);
            end
            
            % limpiar Parametros
            
            ecoFlag = 0;
            tremoloFlag = 0;
            flangerFlag = 0;
            vibratoFlag = 0;
            distFlag = 0;
            
            tremoloValue = 0.5;
            flangerValue = 0.5;
            ecoAlfa = 0;
            ecoDelay = 0;
            vibratoFreqValue = 1;
            vibratoMSValue = 0.5;
            distValue = 20;
            
            
        end

        % Button pushed function: ReproducirarchivoButton
        function ReproducirarchivoButtonPushed(app, event)
            global songData;
            global Fs;
            global fileSelected;
            if isequal(fileSelected, 1)
                soundsc(songData, Fs);
            end
        end

        % Value changing function: GananciadefiltrosSlider
        function GananciadefiltrosSliderValueChanging(app, event)
            global gainValue;
            gainValue = event.Value;            
        end

        % Value changed function: FreqaltaEditField
        function FreqaltaEditFieldValueChanged(app, event)
            global band1HF;
            band1HF = app.FreqaltaEditField.Value;
            app.LampBanda1.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: FreqbajaEditField
        function FreqbajaEditFieldValueChanged(app, event)
            global band1LF;
            band1LF = app.FreqbajaEditField.Value;
            app.LampBanda1.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: FreqaltaEditField_2
        function FreqaltaEditField_2ValueChanged(app, event)
            global band2HF;
            band2HF = app.FreqaltaEditField_2.Value;
            app.LampBanda2.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: FreqbajaEditField_2
        function FreqbajaEditField_2ValueChanged(app, event)
            global band2LF;
            band2LF = app.FreqbajaEditField_2.Value;
            app.LampBanda2.Color = [0.64 0.08 0.18];
            
        end

        % Button pushed function: AplicarBanda1
        function AplicarBanda1ButtonPushed(app, event)
            global songData;
            global band1LF;
            global band1HF;
            global Fs;
            global band1Data;
            band1Data = app.bandPass(songData, band1LF, band1HF, Fs);
            app.LampBanda1.Color = [0.47 0.67 0.19];
            
            %app.efectos1Enable(true);
            
            % for debug
            %app.plotSpectrum(app.EspectroSalidaAxes, band1Data, Fs);
        end

        % Button pushed function: AplicarBanda2
        function AplicarBanda2ButtonPushed(app, event)
            global songData;
            global band2LF;
            global band2HF;
            global Fs;
            global band2Data;
            band2Data = app.bandPass(songData, band2LF, band2HF, Fs);
            app.LampBanda2.Color = [0.47 0.67 0.19];
            
            % for debug
            %app.plotSpectrum(app.EspectroSalidaAxes, band2Data, Fs);
        end

        % Value changed function: TremoloCheckBox
        function TremoloCheckBoxValueChanged(app, event)
            global tremoloFlag;
            tremoloFlag = app.TremoloCheckBox.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
        end

        % Button pushed function: AplicarEfecto1
        function AplicarEfecto1ButtonPushed(app, event)
            global band1Data;
            global Fs;
            global effectSong1;
            global ecoFlag;
            global ecoAlfa;
            global ecoDelay;
            global tremoloFlag;
            global tremoloValue;
            global flangerFlag;
            global flangerValue;
            global vibratoFlag;
            global vibratoFreqValue;
            global vibratoMSValue;
            global distFlag;
            global distValue;
            global channels;

            %4186 Hz Frecuencia mas alta de un piano
            
            effectSong1 = band1Data;
            
            % Aplicar Eco
            if (ecoFlag == 1)
                if (channels == 2)
                    effectSong1(:,1) = app.eco(effectSong1(:,1), ecoAlfa, ecoDelay, Fs);
                    effectSong1(:,2) = app.eco(effectSong1(:,2), ecoAlfa, ecoDelay, Fs);
                else
                    effectSong1 = app.eco(effectSong1, ecoAlfa, ecoDelay, Fs);
                end
            end
            
            % Aplicar tremolo
            if (tremoloFlag == 1)
                if (channels == 2)
                    effectSong1(:,1) = app.tremolo(effectSong1(:,1), tremoloValue, Fs);
                    effectSong1(:,2) = app.tremolo(effectSong1(:,2), tremoloValue, Fs);
                else
                    effectSong1 = app.tremolo(effectSong1, tremoloValue);
                end
            end
            
            % aplicar Flanger
            if (flangerFlag == 1)
                if (channels == 2)
                    effectSong1(:,1) = app.flanger(effectSong1(:,1), flangerValue, Fs);
                    effectSong1(:,2) = app.flanger(effectSong1(:,2), flangerValue, Fs);
                else
                    effectSong1 = app.flanger(effectSong1, flangerValue, Fs);
                end
            end
            
            % aplicar vibrato
            if (vibratoFlag == 1)
                if (channels == 2)
                    effectSong1(:,1) = app.vibrato(effectSong1(:,1), vibratoFreqValue, vibratoMSValue, Fs);
                    effectSong1(:,2) = app.vibrato(effectSong1(:,2), vibratoFreqValue, vibratoMSValue, Fs);
                else
                    effectSong1 = app.vibrato(effectSong1, vibratoFreqValue, vibratoMSValue, Fs);
                end
            end
            
            % aplicar Efecto Ring
            if (distFlag == 1)
                if (channels == 2)
                    effectSong1(:,1) = app.ring(effectSong1(:,1), distValue, Fs);
                    effectSong1(:,2) = app.ring(effectSong1(:,2), distValue, Fs);
                else
                    effectSong1 = app.ring(effectSong1, distValue, Fs);
                end
            end
            app.LampEfecto1.Color = [0.47 0.67 0.19];
        end

        % Button pushed function: ReproducirEfecto1
        function ReproducirEfecto1ButtonPushed(app, event)
            global effectSong1;
            global Fs;
            global fileSelected;
            if isequal(fileSelected, 1)
                soundsc(effectSong1, Fs);
            end
            
            
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            global tremoloValue;
            tremoloValue = app.Slider_2.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
        end

        % Value changed function: FlangerCheckBox
        function FlangerCheckBoxValueChanged(app, event)
            global flangerFlag
            flangerFlag = app.FlangerCheckBox.Value;  
            app.LampEfecto1.Color = [0.64 0.08 0.18];   
        end

        % Value changed function: SliderTremolo
        function SliderTremoloValueChanged(app, event)
            global flangerValue
            flangerValue = app.SliderTremolo.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
        end

        % Value changed function: VibratoCheckBox
        function VibratoCheckBoxValueChanged(app, event)
            global vibratoFlag;
            vibratoFlag = app.VibratoCheckBox.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
        end

        % Value changed function: SliderVibratoFreq
        function SliderVibratoFreqValueChanged(app, event)
            global vibratoFreqValue;
            vibratoFreqValue = app.SliderVibratoFreq.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
        end

        % Value changed function: SliderVibratoMS
        function SliderVibratoMSValueChanged(app, event)
            global vibratoMSValue;
            vibratoMSValue= app.SliderVibratoMS.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
        end

        % Value changed function: DistCheckBox
        function DistCheckBoxValueChanged(app, event)
            global distFlag;
            distFlag = app.DistCheckBox.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
        end

        % Value changed function: SliderDistVal
        function SliderDistValValueChanged(app, event)
            global distValue;
            distValue = app.SliderDistVal.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
        end

        % Value changed function: EcoCheckBox
        function EcoCheckBoxValueChanged(app, event)
            global ecoFlag;
            ecoFlag = app.EcoCheckBox.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
        end

        % Value changed function: SliderEcoDelay
        function SliderEcoDelayValueChanged(app, event)
            global ecoDelay;
            ecoDelay = app.SliderEcoDelay.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
        end

        % Value changed function: SliderEcoAlfa
        function SliderEcoAlfaValueChanged(app, event)
            global ecoAlfa
            ecoAlfa = app.SliderEcoAlfa.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UnidaddeEfectosdeAudioUIFigure = uifigure;
            app.UnidaddeEfectosdeAudioUIFigure.Name = 'Unidad de Efectos de Audio';
            app.Efectos1Panel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.Efectos1Panel.Title = 'Efectos';
            app.EcoCheckBox = uicheckbox(app.Efectos1Panel);
            app.EcoCheckBox.ValueChangedFcn = createCallbackFcn(app, @EcoCheckBoxValueChanged, true);
            app.EcoCheckBox.Text = 'Eco';
            app.TremoloCheckBox = uicheckbox(app.Efectos1Panel);
            app.TremoloCheckBox.ValueChangedFcn = createCallbackFcn(app, @TremoloCheckBoxValueChanged, true);
            app.TremoloCheckBox.Text = 'Tremolo';
            app.FlangerCheckBox = uicheckbox(app.Efectos1Panel);
            app.FlangerCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlangerCheckBoxValueChanged, true);
            app.FlangerCheckBox.Text = 'Flanger';
            app.DistCheckBox = uicheckbox(app.Efectos1Panel);
            app.DistCheckBox.ValueChangedFcn = createCallbackFcn(app, @DistCheckBoxValueChanged, true);
            app.DistCheckBox.Text = 'Dist.';
            app.VibratoCheckBox = uicheckbox(app.Efectos1Panel);
            app.VibratoCheckBox.ValueChangedFcn = createCallbackFcn(app, @VibratoCheckBoxValueChanged, true);
            app.VibratoCheckBox.Text = 'Vibrato';
            app.AplicarEfecto1 = uibutton(app.Efectos1Panel, 'push');
            app.AplicarEfecto1.ButtonPushedFcn = createCallbackFcn(app, @AplicarEfecto1ButtonPushed, true);
            app.AplicarEfecto1.Text = 'Aplicar';
            app.ReproducirEfecto1 = uibutton(app.Efectos1Panel, 'push');
            app.ReproducirEfecto1.ButtonPushedFcn = createCallbackFcn(app, @ReproducirEfecto1ButtonPushed, true);
            app.ReproducirEfecto1.Text = 'Reproducir';
            app.LampEfecto1 = uilamp(app.Efectos1Panel);
            app.SliderEcoDelay = uislider(app.Efectos1Panel);
            app.SliderEcoDelay.Limits = [0 5];
            app.SliderEcoDelay.ValueChangedFcn = createCallbackFcn(app, @SliderEcoDelayValueChanged, true);
            app.SecLabel = uilabel(app.Efectos1Panel);
            app.SecLabel.Text = 'Sec';
            app.Slider_2 = uislider(app.Efectos1Panel);
            app.Slider_2.Limits = [0.5 5];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Value = 0.5;
            app.HzLabel_5 = uilabel(app.Efectos1Panel);
            app.HzLabel_5.Text = 'Hz';
            app.SliderTremolo = uislider(app.Efectos1Panel);
            app.SliderTremolo.Limits = [0.5 5];
            app.SliderTremolo.ValueChangedFcn = createCallbackFcn(app, @SliderTremoloValueChanged, true);
            app.SliderTremolo.Value = 0.5;
            app.SliderVibratoFreq = uislider(app.Efectos1Panel);
            app.SliderVibratoFreq.Limits = [1 15];
            app.SliderVibratoFreq.ValueChangedFcn = createCallbackFcn(app, @SliderVibratoFreqValueChanged, true);
            app.SliderVibratoFreq.Value = 1;
            app.HzLabel_6 = uilabel(app.Efectos1Panel);
            app.HzLabel_6.Text = 'Hz';
            app.HzLabel_7 = uilabel(app.Efectos1Panel);
            app.HzLabel_7.Text = 'Hz';
            app.SliderVibratoMS = uislider(app.Efectos1Panel);
            app.SliderVibratoMS.Limits = [0.5 10];
            app.SliderVibratoMS.ValueChangedFcn = createCallbackFcn(app, @SliderVibratoMSValueChanged, true);
            app.SliderVibratoMS.Value = 0.5;
            app.msLabel = uilabel(app.Efectos1Panel);
            app.msLabel.Text = 'ms';
            app.SliderDistVal = uislider(app.Efectos1Panel);
            app.SliderDistVal.Limits = [20 6000];
            app.SliderDistVal.ValueChangedFcn = createCallbackFcn(app, @SliderDistValValueChanged, true);
            app.SliderDistVal.Value = 20;
            app.HzLabel_8 = uilabel(app.Efectos1Panel);
            app.HzLabel_8.Text = 'Hz';
            app.SliderEcoAlfa = uislider(app.Efectos1Panel);
            app.SliderEcoAlfa.Limits = [0 1];
            app.SliderEcoAlfa.ValueChangedFcn = createCallbackFcn(app, @SliderEcoAlfaValueChanged, true);
            app.SecLabel_2 = uilabel(app.Efectos1Panel);
            app.SecLabel_2.Text = 'Sec';
            app.ArchivoEntradaPanel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.ArchivoEntradaPanel.Title = 'Archivo Entrada';
            app.ExaminarButton_2 = uibutton(app.ArchivoEntradaPanel, 'push');
            app.ExaminarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExaminarButton_2Pushed, true);
            app.ExaminarButton_2.Text = 'Examinar';
            app.ReproducirarchivoButton = uibutton(app.ArchivoEntradaPanel, 'push');
            app.ReproducirarchivoButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirarchivoButtonPushed, true);
            app.ReproducirarchivoButton.Text = 'Reproducir archivo';
            app.ArchivoAudioLabel = uilabel(app.ArchivoEntradaPanel);
            app.ArchivoAudioLabel.Text = '<Archivo Audio>';
            app.Panel_2 = uipanel(app.ArchivoEntradaPanel);
            app.DuracionLabel = uilabel(app.Panel_2);
            app.DuracionLabel.Text = 'Duracion';
            app.Panel_3 = uipanel(app.Panel_2);
            app.DurationLabel = uilabel(app.Panel_3);
            app.DurationLabel.Text = '00:00';
            app.CanalesLabel = uilabel(app.Panel_2);
            app.CanalesLabel.Text = 'Canales';
            app.Panel_4 = uipanel(app.Panel_2);
            app.NumCanalesLabel = uilabel(app.Panel_4);
            app.NumCanalesLabel.Text = '-';
            app.Banda1Panel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.Banda1Panel.Title = 'Banda 1';
            app.FreqaltaEditFieldLabel = uilabel(app.Banda1Panel);
            app.FreqaltaEditFieldLabel.Text = 'Freq. alta';
            app.FreqaltaEditField = uieditfield(app.Banda1Panel, 'numeric');
            app.FreqaltaEditField.Limits = [0 20000];
            app.FreqaltaEditField.ValueChangedFcn = createCallbackFcn(app, @FreqaltaEditFieldValueChanged, true);
            app.FreqbajaEditFieldLabel = uilabel(app.Banda1Panel);
            app.FreqbajaEditFieldLabel.Text = 'Freq. baja';
            app.FreqbajaEditField = uieditfield(app.Banda1Panel, 'numeric');
            app.FreqbajaEditField.Limits = [0 20000];
            app.FreqbajaEditField.ValueChangedFcn = createCallbackFcn(app, @FreqbajaEditFieldValueChanged, true);
            app.HzLabel = uilabel(app.Banda1Panel);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.Banda1Panel);
            app.HzLabel_2.Text = 'Hz';
            app.AplicarBanda1 = uibutton(app.Banda1Panel, 'push');
            app.AplicarBanda1.ButtonPushedFcn = createCallbackFcn(app, @AplicarBanda1ButtonPushed, true);
            app.AplicarBanda1.Text = 'Aplicar';
            app.LampBanda1 = uilamp(app.Banda1Panel);
            app.EfectosPanel_2 = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.EfectosPanel_2.Title = 'Efectos';
            app.AplicarEfecto2 = uibutton(app.EfectosPanel_2, 'push');
            app.AplicarEfecto2.Text = 'Aplicar';
            app.ReproducirEfecto2 = uibutton(app.EfectosPanel_2, 'push');
            app.ReproducirEfecto2.Text = 'Reproducir';
            app.LampEfecto2 = uilamp(app.EfectosPanel_2);
            app.EcoCheckBox_2 = uicheckbox(app.EfectosPanel_2);
            app.EcoCheckBox_2.Text = 'Eco';
            app.TremoloCheckBox_2 = uicheckbox(app.EfectosPanel_2);
            app.TremoloCheckBox_2.Text = 'Tremolo';
            app.FlangerCheckBox_2 = uicheckbox(app.EfectosPanel_2);
            app.FlangerCheckBox_2.Text = 'Flanger';
            app.EfectoCheckBox_7 = uicheckbox(app.EfectosPanel_2);
            app.EfectoCheckBox_7.Text = 'Efecto';
            app.EfectoCheckBox_8 = uicheckbox(app.EfectosPanel_2);
            app.EfectoCheckBox_8.Text = 'Efecto';
            app.Slider_4 = uislider(app.EfectosPanel_2);
            app.Slider_5 = uislider(app.EfectosPanel_2);
            app.Slider_5.Limits = [0.5 5];
            app.Slider_5.Value = 0.5;
            app.Slider_6 = uislider(app.EfectosPanel_2);
            app.Slider_6.Limits = [0.5 5];
            app.Slider_6.Value = 0.5;
            app.GananciaPanel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.GananciaPanel.Title = 'Ganancia';
            app.GananciadefiltrosSlider = uislider(app.GananciaPanel);
            app.GananciadefiltrosSlider.Limits = [0 1];
            app.GananciadefiltrosSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.GananciadefiltrosSlider.ValueChangingFcn = createCallbackFcn(app, @GananciadefiltrosSliderValueChanging, true);
            app.AplicarGanancia = uibutton(app.GananciaPanel, 'push');
            app.AplicarGanancia.Text = 'Aplicar';
            app.ReproducirGanancia = uibutton(app.GananciaPanel, 'push');
            app.ReproducirGanancia.Text = 'Reproducir';
            app.LampGanancia = uilamp(app.GananciaPanel);
            app.Banda2Panel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.Banda2Panel.Title = 'Banda 2';
            app.FreqaltaEditField_2Label = uilabel(app.Banda2Panel);
            app.FreqaltaEditField_2Label.Text = 'Freq. alta';
            app.FreqaltaEditField_2 = uieditfield(app.Banda2Panel, 'numeric');
            app.FreqaltaEditField_2.Limits = [0 20000];
            app.FreqaltaEditField_2.ValueChangedFcn = createCallbackFcn(app, @FreqaltaEditField_2ValueChanged, true);
            app.FreqbajaEditField_2Label = uilabel(app.Banda2Panel);
            app.FreqbajaEditField_2Label.Text = 'Freq. baja';
            app.FreqbajaEditField_2 = uieditfield(app.Banda2Panel, 'numeric');
            app.FreqbajaEditField_2.Limits = [0 20000];
            app.FreqbajaEditField_2.ValueChangedFcn = createCallbackFcn(app, @FreqbajaEditField_2ValueChanged, true);
            app.HzLabel_3 = uilabel(app.Banda2Panel);
            app.HzLabel_3.Text = 'Hz';
            app.HzLabel_4 = uilabel(app.Banda2Panel);
            app.HzLabel_4.Text = 'Hz';
            app.AplicarBanda2 = uibutton(app.Banda2Panel, 'push');
            app.AplicarBanda2.ButtonPushedFcn = createCallbackFcn(app, @AplicarBanda2ButtonPushed, true);
            app.AplicarBanda2.Text = 'Aplicar';
            app.LampBanda2 = uilamp(app.Banda2Panel);
            app.EspectroArchivoEntradaPanel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.EspectroArchivoEntradaPanel.Title = 'Espectro Archivo Entrada';
            app.EspectroEntradaAxes = uiaxes(app.EspectroArchivoEntradaPanel);
            xlabel(app.EspectroEntradaAxes, 'Frecuencia (Hz)')
            ylabel(app.EspectroEntradaAxes, 'Magnitud (dB)')
            app.EspectroEfectoPanel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.EspectroEfectoPanel.Title = 'Espectro Efecto';
            app.EspectroEfectoAxes = uiaxes(app.EspectroEfectoPanel);
            xlabel(app.EspectroEfectoAxes, 'Frecuencia (Hz)')
            ylabel(app.EspectroEfectoAxes, 'Magnitud (dB)')
            app.EfectoDropDownLabel = uilabel(app.EspectroEfectoPanel);
            app.EfectoDropDownLabel.Text = 'Efecto';
            app.EfectoDropDown = uidropdown(app.EspectroEfectoPanel);
            app.EspectroArchivoSalidaPanel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.EspectroArchivoSalidaPanel.Title = 'Espectro Archivo Salida';
            app.EspectroSalidaAxes = uiaxes(app.EspectroArchivoSalidaPanel);
            xlabel(app.EspectroSalidaAxes, 'Frecuencia (Hz)')
            ylabel(app.EspectroSalidaAxes, 'Magnitud (dB)')
            app.ArchivoSalidaPanel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.ArchivoSalidaPanel.Title = 'Archivo Salida';
            app.ExaminarButton_3 = uibutton(app.ArchivoSalidaPanel, 'push');
            app.ExaminarButton_3.Text = 'Examinar';
            app.GuardarButton = uibutton(app.ArchivoSalidaPanel, 'push');
            app.GuardarButton.Text = 'Guardar';
            app.ArchivoAudioLabel_2 = uilabel(app.ArchivoSalidaPanel);
            app.ArchivoAudioLabel_2.Text = '<Archivo Audio>';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ProyectoApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UnidaddeEfectosdeAudioUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UnidaddeEfectosdeAudioUIFigure)
        end
    end
end